classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        EncryptTab                     matlab.ui.container.Tab
        ShiftTextArea_Encrypt          matlab.ui.control.TextArea
        ShiftTextAreaLabel             matlab.ui.control.Label
        EnterStringTextArea            matlab.ui.control.TextArea
        EnterStringLabel               matlab.ui.control.Label
        EncryptedTextTextArea_Encrypt  matlab.ui.control.TextArea
        EncryptedTextTextArea_2Label   matlab.ui.control.Label
        KeyTextArea_Encrypt            matlab.ui.control.TextArea
        KeyTextArea_2Label             matlab.ui.control.Label
        EncryptButton                  matlab.ui.control.Button
        DecryptTab                     matlab.ui.container.Tab
        ShiftTextArea_Decrypt          matlab.ui.control.TextArea
        ShiftTextArea_2Label           matlab.ui.control.Label
        KeyTextArea_Decrypt            matlab.ui.control.TextArea
        KeyTextArea_3Label             matlab.ui.control.Label
        EnterEncryptedStringTextArea   matlab.ui.control.TextArea
        EnterEncryptedStringLabel      matlab.ui.control.Label
        DecryptedTextTextArea          matlab.ui.control.TextArea
        DecryptedTextTextAreaLabel     matlab.ui.control.Label
        DecryptButton                  matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function asciistr = toascii(~, string)
            letters = {'a', 'b', 'c', 'd', 'e', 'f', 'g', 'h', 'i', 'j', ...
                       'k', 'l', 'm', 'n', 'o', 'p', 'q', 'r', 's', 't', ...
                       'u', 'v', 'w', 'x', 'y', 'z', '', ',', '.'};
            nums = 0:28;
            d = dictionary(letters, nums);
            string = lower(string);
            chars = cellstr(string(:));
            asciistr = d(chars);
        end
        
        function str = tochar(~, asciistr)
            nums = 0:28;
            letters = {'a', 'b', 'c', 'd', 'e', 'f', 'g', 'h', 'i', 'j', ...
                       'k', 'l', 'm', 'n', 'o', 'p', 'q', 'r', 's', 't', ...
                       'u', 'v', 'w', 'x', 'y', 'z', ' ', ',', '.'};
            d = dictionary(nums, letters);
            chars = d(asciistr);
            str = [chars{:}];            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EncryptButton
        function EncryptButtonPushed(app, event)
            str=char(app.EnterStringTextArea.Value);
            strsize = strlength(str);
            asciistr = toascii(app, str);
            keysize = randi([1, min(strsize, 10)]);
            key = mod(randi(29, keysize),29);
            while gcd(int64(det(key)),29) ~= 1
                key = mod(key + eye(keysize), 29);
            end
            if mod(strsize, keysize) ~= 0
                zerosize = keysize-mod(strsize,keysize);
                asciistr = [asciistr; zeros(zerosize,1) + 26];
            end
            shift = randi(29, length(asciistr), 1);
            start = 1;
            enctxt = char(length(asciistr));
            while start < length(asciistr)
                encmatrix = mod(key*asciistr(start:start+keysize-1), 29);
                encmatrix = mod(encmatrix + shift(start:start+keysize-1),29);
                enctxt(start:start+keysize-1) = tochar(app,encmatrix.');
                start = start + keysize;
            end
            app.EncryptedTextTextArea_Encrypt.Value = enctxt;
            app.KeyTextArea_Encrypt.Value = strtrim(cellstr(num2str(key)));
            app.ShiftTextArea_Encrypt.Value = strtrim(cellstr(num2str(shift)))
        end

        % Button pushed function: DecryptButton
        function DecryptButtonPushed(app, event)
            str=char(app.EnterEncryptedStringTextArea.Value);
            strsize = strlength(str);
            asciistr = toascii(app, str);
            key = app.KeyTextArea_Decrypt.Value;
            key = cell2mat(cellfun(@str2num, key, 'UniformOutput', false));
            shift = app.ShiftTextArea_Decrypt.Value;
            shift = cell2mat(cellfun(@str2num, shift, 'UniformOutput', false));
            [~, detinv] = gcd(int64(det(key)), 29);
            detinv = mod(detinv, 29);
            keyinv = mod(detinv*adjoint(sym(key)), 29);
            keysize = length(key);
            asciistr = asciistr - shift;
            start = 1;
            enctxt = char(length(asciistr));
            while start < length(asciistr)
                 encmatrix = mod(keyinv*asciistr(start:start+keysize-1), 29);
                 enctxt(start:start+keysize-1) = tochar(app,encmatrix.');
                 start = start + keysize;
            end
            app.DecryptedTextTextArea.Value = enctxt(1:strsize);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EncryptTab = uitab(app.TabGroup);
            app.EncryptTab.Title = 'Encrypt';
            app.EncryptButton = uibutton(app.EncryptTab, 'push');
            app.EncryptButton.ButtonPushedFcn = createCallbackFcn(app, @EncryptButtonPushed, true);
            app.EncryptButton.Text = 'Encrypt';
            app.KeyTextArea_2Label = uilabel(app.EncryptTab);
            app.KeyTextArea_2Label.Text = 'Key';
            app.KeyTextArea_Encrypt = uitextarea(app.EncryptTab);
            app.KeyTextArea_Encrypt.Editable = 'off';
            app.EncryptedTextTextArea_2Label = uilabel(app.EncryptTab);
            app.EncryptedTextTextArea_2Label.Text = 'Encrypted Text';
            app.EncryptedTextTextArea_Encrypt = uitextarea(app.EncryptTab);
            app.EncryptedTextTextArea_Encrypt.Editable = 'off';
            app.EnterStringLabel = uilabel(app.EncryptTab);
            app.EnterStringLabel.Text = 'Enter String:';
            app.EnterStringTextArea = uitextarea(app.EncryptTab);
            app.ShiftTextAreaLabel = uilabel(app.EncryptTab);
            app.ShiftTextAreaLabel.Text = 'Shift';
            app.ShiftTextArea_Encrypt = uitextarea(app.EncryptTab);
            app.ShiftTextArea_Encrypt.Editable = 'off';
            app.DecryptTab = uitab(app.TabGroup);
            app.DecryptTab.Title = 'Decrypt';
            app.DecryptButton = uibutton(app.DecryptTab, 'push');
            app.DecryptButton.ButtonPushedFcn = createCallbackFcn(app, @DecryptButtonPushed, true);
            app.DecryptButton.Text = 'Decrypt';
            app.DecryptedTextTextAreaLabel = uilabel(app.DecryptTab);
            app.DecryptedTextTextAreaLabel.Text = 'Decrypted Text';
            app.DecryptedTextTextArea = uitextarea(app.DecryptTab);
            app.DecryptedTextTextArea.Editable = 'off';
            app.EnterEncryptedStringLabel = uilabel(app.DecryptTab);
            app.EnterEncryptedStringLabel.Text = {'Enter'; 'Encrypted String:'};
            app.EnterEncryptedStringTextArea = uitextarea(app.DecryptTab);
            app.KeyTextArea_3Label = uilabel(app.DecryptTab);
            app.KeyTextArea_3Label.Text = 'Key';
            app.KeyTextArea_Decrypt = uitextarea(app.DecryptTab);
            app.ShiftTextArea_2Label = uilabel(app.DecryptTab);
            app.ShiftTextArea_2Label.Text = 'Shift';
            app.ShiftTextArea_Decrypt = uitextarea(app.DecryptTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end